classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LeftGridLayout             matlab.ui.container.GridLayout
        SettingsLabel              matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        VisionTab                  matlab.ui.container.Tab
        VisionGridLayout           matlab.ui.container.GridLayout
        AquireImageButton          matlab.ui.control.Button
        ZDepthEditFieldLabel       matlab.ui.control.Label
        ZDepthEditField            matlab.ui.control.NumericEditField
        CalculateNormalsButton     matlab.ui.control.Button
        PathingTab                 matlab.ui.container.Tab
        PathingGridLayout          matlab.ui.container.GridLayout
        AcquirePointsButton        matlab.ui.control.Button
        DownsampleEditFieldLabel   matlab.ui.control.Label
        DownsampleEditField        matlab.ui.control.NumericEditField
        DownsampleButton           matlab.ui.control.Button
        MaxDistanceEditFieldLabel  matlab.ui.control.Label
        MaxDistanceEditField       matlab.ui.control.NumericEditField
        AveragingEditFieldLabel    matlab.ui.control.Label
        AveragingEditField         matlab.ui.control.NumericEditField
        GeneratePathButton         matlab.ui.control.Button
        SmoothingEditFieldLabel    matlab.ui.control.Label
        SmoothingEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel      matlab.ui.control.Label
        SamplesEditField           matlab.ui.control.NumericEditField
        ResampleButton_2           matlab.ui.control.Button
        RMRCTab                    matlab.ui.container.Tab
        RMRCGridLayout             matlab.ui.container.GridLayout
        CollisionCheckSwitchLabel  matlab.ui.control.Label
        CollisionCheckSwitch       matlab.ui.control.Switch
        LoadTrajectoryButton       matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        RightGridLayout            matlab.ui.container.GridLayout
        ControlLabel               matlab.ui.control.Label
        EStopButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        DobotStatsPanel            matlab.ui.container.Panel
        DobotJointsGridLayout      matlab.ui.container.GridLayout
        Joint1EditFieldLabel       matlab.ui.control.Label
        DobotJoint1EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        DobotJoint2EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        DobotJoint3EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel       matlab.ui.control.Label
        DobotJoint4EditField       matlab.ui.control.NumericEditField
        Joint5EditFieldLabel       matlab.ui.control.Label
        DobotJoint5EditField       matlab.ui.control.NumericEditField
        LogTextArea                matlab.ui.control.TextArea
        StatusGridLayout           matlab.ui.container.GridLayout
        StatusIndicatorLampLabel   matlab.ui.control.Label
        StatusIndicatorLamp        matlab.ui.control.Lamp
        ARTagsPositionsPanel       matlab.ui.container.Panel
        ARGridLayout               matlab.ui.container.GridLayout
        PathEditFieldLabel         matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        CameraEditFieldLabel       matlab.ui.control.Label
        CameraEditField            matlab.ui.control.EditField
        DobotEditFieldLabel        matlab.ui.control.Label
        DobotEditField             matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Intialised % Only run robot once path has been set
    end
    
    methods (Access = private)
        function SetStatus(app, code)
            switch(lower(code))
                case 'environmentnotsetup'
                    colour = 'yellow';
                    text = 'Environment not setup';
                case 'environmentsettingup'
                    colour = 'yellow';
                    text = 'Environment setting up';
                case 'environmentsetup'
                    colour = 'green';
                    text = 'Environment setup';
                case 'running'
                    colour = 'green';
                    text = 'Simulation running';           
                case 'estop'
                    colour = 'red';
                    text = 'E Stop triggered, reset environment to continue';
                case 'calculateworkspace'
                    colour = 'yellow';
                    text = 'Calculating workspace propeties... this could take a while!';
                otherwise
                    colour = 'magenta';
                    text = 'Unknown status code';
            end
            app.StatusIndicatorLamp.Color = colour;
            app.StatusIndicatorLampLabel.Text = text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ros)
            app.ros_mode = ros;
        end

        % Button pushed function: AquireImageButton
        function AquireImageButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.SettingsLabel = uilabel(app.LeftGridLayout);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.LeftGridLayout);
            app.VisionTab = uitab(app.TabGroup);
            app.VisionTab.Title = 'Vision';
            app.VisionGridLayout = uigridlayout(app.VisionTab);
            app.AquireImageButton = uibutton(app.VisionGridLayout, 'push');
            app.AquireImageButton.ButtonPushedFcn = createCallbackFcn(app, @AquireImageButtonPushed, true);
            app.AquireImageButton.Text = 'Aquire Image';
            app.ZDepthEditFieldLabel = uilabel(app.VisionGridLayout);
            app.ZDepthEditFieldLabel.Text = 'Z Depth';
            app.ZDepthEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.CalculateNormalsButton = uibutton(app.VisionGridLayout, 'push');
            app.CalculateNormalsButton.Text = 'Calculate Normals';
            app.PathingTab = uitab(app.TabGroup);
            app.PathingTab.Title = 'Pathing';
            app.PathingGridLayout = uigridlayout(app.PathingTab);
            app.AcquirePointsButton = uibutton(app.PathingGridLayout, 'push');
            app.AcquirePointsButton.Text = 'Acquire Points';
            app.DownsampleEditFieldLabel = uilabel(app.PathingGridLayout);
            app.DownsampleEditFieldLabel.Text = 'Downsample';
            app.DownsampleEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.DownsampleEditField.Value = 200;
            app.DownsampleButton = uibutton(app.PathingGridLayout, 'push');
            app.DownsampleButton.Text = 'Downsample';
            app.MaxDistanceEditFieldLabel = uilabel(app.PathingGridLayout);
            app.MaxDistanceEditFieldLabel.Text = 'Max Distance';
            app.MaxDistanceEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.MaxDistanceEditField.Value = 0.1;
            app.AveragingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.AveragingEditField.Value = 4;
            app.GeneratePathButton = uibutton(app.PathingGridLayout, 'push');
            app.GeneratePathButton.Text = 'Generate Path';
            app.SmoothingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SmoothingEditField.Value = 0.5;
            app.SamplesEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SamplesEditField.Value = 400;
            app.ResampleButton_2 = uibutton(app.PathingGridLayout, 'push');
            app.ResampleButton_2.Text = 'Resample';
            app.RMRCTab = uitab(app.TabGroup);
            app.RMRCTab.Title = 'RMRC';
            app.RMRCGridLayout = uigridlayout(app.RMRCTab);
            app.CollisionCheckSwitchLabel = uilabel(app.RMRCGridLayout);
            app.CollisionCheckSwitchLabel.Text = 'Collision Check';
            app.CollisionCheckSwitch = uiswitch(app.RMRCGridLayout, 'slider');
            app.LoadTrajectoryButton = uibutton(app.RMRCGridLayout, 'push');
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGridLayout = uigridlayout(app.RightPanel);
            app.ControlLabel = uilabel(app.RightGridLayout);
            app.ControlLabel.Text = 'Control';
            app.EStopButton = uibutton(app.RightGridLayout, 'push');
            app.EStopButton.Text = 'E Stop';
            app.StartButton = uibutton(app.RightGridLayout, 'push');
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DobotStatsPanel = uipanel(app.RightGridLayout);
            app.DobotStatsPanel.Title = 'Dobot Stats';
            app.DobotJointsGridLayout = uigridlayout(app.DobotStatsPanel);
            app.Joint1EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.DobotJoint1EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint1EditField.Editable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.DobotJoint2EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint2EditField.Editable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.DobotJoint3EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint3EditField.Editable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.DobotJoint4EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint4EditField.Editable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.DobotJoint5EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint5EditField.Editable = 'off';
            app.LogTextArea = uitextarea(app.RightGridLayout);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Tooltip = {'Execution Log'};
            app.StatusGridLayout = uigridlayout(app.RightGridLayout);
            app.StatusIndicatorLampLabel = uilabel(app.StatusGridLayout);
            app.StatusIndicatorLampLabel.Text = 'Status Indicator';
            app.StatusIndicatorLamp = uilamp(app.StatusGridLayout);
            app.ARTagsPositionsPanel = uipanel(app.RightGridLayout);
            app.ARTagsPositionsPanel.Title = 'AR Tags Positions';
            app.ARGridLayout = uigridlayout(app.ARTagsPositionsPanel);
            app.PathEditFieldLabel = uilabel(app.ARGridLayout);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ARGridLayout, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = 'N/A';
            app.CameraEditFieldLabel = uilabel(app.ARGridLayout);
            app.CameraEditFieldLabel.Text = 'Camera';
            app.CameraEditField = uieditfield(app.ARGridLayout, 'text');
            app.CameraEditField.Editable = 'off';
            app.CameraEditField.Value = 'N/A';
            app.DobotEditFieldLabel = uilabel(app.ARGridLayout);
            app.DobotEditFieldLabel.Text = 'Dobot';
            app.DobotEditField = uieditfield(app.ARGridLayout, 'text');
            app.DobotEditField.Editable = 'off';
            app.DobotEditField.Value = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end